classdef NPselectionPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NPL        matlab.ui.Figure
        text0      matlab.ui.control.Label
        btnDone    matlab.ui.control.Button
        Image      matlab.ui.control.Image
        Select_P1  matlab.ui.control.Button
        Edit_P1    matlab.ui.control.EditField
        Select_P2  matlab.ui.control.Button
        Edit_P2    matlab.ui.control.EditField
        Select_P3  matlab.ui.control.Button
        Edit_P3    matlab.ui.control.EditField
        Select_P4  matlab.ui.control.Button
        Edit_P4    matlab.ui.control.EditField
        Select_P5  matlab.ui.control.Button
        Edit_P5    matlab.ui.control.EditField
        Select_P6  matlab.ui.control.Button
        Edit_P6    matlab.ui.control.EditField
        Select_P7  matlab.ui.control.Button
        Edit_P7    matlab.ui.control.EditField
        Select_P8  matlab.ui.control.Button
        Edit_P8    matlab.ui.control.EditField
        Select_P9  matlab.ui.control.Button
        Edit_P9    matlab.ui.control.EditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ScreenAnalysis_Main_APP)
            app.CallingApp = ScreenAnalysis_Main_APP;
            
        end

        % Callback function
        function btnBack_Callback(app, event)
           
        end

        % Button pushed function: btnDone
        function btnDone_Callback(app, event)
            
        end

        % Close request function: NPL
        function NPL_CloseRequestFcn(app, event)
            delete(app)
        end

        % Button pushed function: Select_P1
        function Select_P1ButtonPushed(app, event)
            app.fileP1=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the Front-Left Image.');
            app.Edit_P1.Value=app.fileP1(1:end-4);
        end

        % Button pushed function: Select_P2
        function Select_P2ButtonPushed(app, event)
            app.fileP2=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the Front-Center Image.');
            app.Edit_P1.Value=app.fileP2(1:end-4);
        end

        % Button pushed function: Select_P3
        function Select_P3ButtonPushed(app, event)
            app.fileP3=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the Front-Right Image.');
            app.Edit_P3.Value=app.fileP3(1:end-4);
        end

        % Button pushed function: Select_P4
        function Select_P4ButtonPushed(app, event)
            app.fileP4=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the Center-Left Image.');
            app.Edit_P4.Value=app.fileP4(1:end-4);
        end

        % Button pushed function: Select_P5
        function Select_P5ButtonPushed(app, event)
            app.fileP5=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the Center-Center Image.');
            app.Edit_P5.Value=app.fileP5(1:end-4);
        end

        % Button pushed function: Select_P6
        function Select_P6ButtonPushed(app, event)
            app.fileP6=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the Center-Right Image.');
            app.Edit_P6.Value=app.fileP6(1:end-4);
        end

        % Button pushed function: Select_P7
        function Select_P7ButtonPushed(app, event)
            app.fileP7=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the Rear-Left Image.');
            app.Edit_P7.Value=app.fileP7(1:end-4);
        end

        % Button pushed function: Select_P8
        function Select_P8ButtonPushed(app, event)
            app.fileP8=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the Rear-Center Image.');
            app.Edit_P8.Value=app.fileP8(1:end-4);
        end

        % Button pushed function: Select_P9
        function Select_P9ButtonPushed(app, event)
            app.fileP9=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the Rear-Right Image.');
            app.Edit_P9.Value=app.fileP9(1:end-4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NPL = uifigure('Visible', 'off');
            app.NPL.Name = '9 Points File Load';
            app.NPL.CloseRequestFcn = createCallbackFcn(app, @NPL_CloseRequestFcn, true);
            app.text0 = uilabel(app.NPL);
            app.text0.Text = '9 Points Measurements';
            app.btnDone = uibutton(app.NPL, 'push');
            app.btnDone.ButtonPushedFcn = createCallbackFcn(app, @btnDone_Callback, true);
            app.btnDone.Text = 'Close';
            app.Image = uiimage(app.NPL);
            app.Image.ImageSource = 'Layout.png';
            app.Select_P1 = uibutton(app.NPL, 'push');
            app.Select_P1.ButtonPushedFcn = createCallbackFcn(app, @Select_P1ButtonPushed, true);
            app.Select_P1.Text = 'P1';
            app.Edit_P1 = uieditfield(app.NPL, 'text');
            app.Select_P2 = uibutton(app.NPL, 'push');
            app.Select_P2.ButtonPushedFcn = createCallbackFcn(app, @Select_P2ButtonPushed, true);
            app.Select_P2.Text = 'P2';
            app.Edit_P2 = uieditfield(app.NPL, 'text');
            app.Select_P3 = uibutton(app.NPL, 'push');
            app.Select_P3.ButtonPushedFcn = createCallbackFcn(app, @Select_P3ButtonPushed, true);
            app.Select_P3.Text = 'P3';
            app.Edit_P3 = uieditfield(app.NPL, 'text');
            app.Select_P4 = uibutton(app.NPL, 'push');
            app.Select_P4.ButtonPushedFcn = createCallbackFcn(app, @Select_P4ButtonPushed, true);
            app.Select_P4.Text = 'P4';
            app.Edit_P4 = uieditfield(app.NPL, 'text');
            app.Select_P5 = uibutton(app.NPL, 'push');
            app.Select_P5.ButtonPushedFcn = createCallbackFcn(app, @Select_P5ButtonPushed, true);
            app.Select_P5.Text = 'P5';
            app.Edit_P5 = uieditfield(app.NPL, 'text');
            app.Select_P6 = uibutton(app.NPL, 'push');
            app.Select_P6.ButtonPushedFcn = createCallbackFcn(app, @Select_P6ButtonPushed, true);
            app.Select_P6.Text = 'P6';
            app.Edit_P6 = uieditfield(app.NPL, 'text');
            app.Select_P7 = uibutton(app.NPL, 'push');
            app.Select_P7.ButtonPushedFcn = createCallbackFcn(app, @Select_P7ButtonPushed, true);
            app.Select_P7.Text = 'P7';
            app.Edit_P7 = uieditfield(app.NPL, 'text');
            app.Select_P8 = uibutton(app.NPL, 'push');
            app.Select_P8.ButtonPushedFcn = createCallbackFcn(app, @Select_P8ButtonPushed, true);
            app.Select_P8.Text = 'P8';
            app.Edit_P8 = uieditfield(app.NPL, 'text');
            app.Select_P9 = uibutton(app.NPL, 'push');
            app.Select_P9.ButtonPushedFcn = createCallbackFcn(app, @Select_P9ButtonPushed, true);
            app.Select_P9.Text = 'P9';
            app.Edit_P9 = uieditfield(app.NPL, 'text');
            % Show the figure after all components are created
            app.NPL.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NPselectionPopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NPL)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NPL)
        end
    end
end