classdef ScreenAnalysis_Main_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScreenAnalysis          matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        DataFileSelectionLabel  matlab.ui.control.Label
        NameEditFieldLabel      matlab.ui.control.Label
        NameEditField           matlab.ui.control.EditField
        CalculateButton         matlab.ui.control.Button
        ClearAllButton          matlab.ui.control.Button
        Select_2DB              matlab.ui.control.Button
        Select_Folder           matlab.ui.control.Button
        Select_3DRB             matlab.ui.control.Button
        Select_3DRD             matlab.ui.control.Button
        Select_3DLB             matlab.ui.control.Button
        Select_3DLD             matlab.ui.control.Button
        Edit_Folder             matlab.ui.control.EditField
        Edit_2DB                matlab.ui.control.EditField
        Edit_3DRB               matlab.ui.control.EditField
        Edit_3DRD               matlab.ui.control.EditField
        Edit_3DLB               matlab.ui.control.EditField
        Edit_3DLD               matlab.ui.control.EditField
        Image                   matlab.ui.control.Image
        TabGroup                matlab.ui.container.TabGroup
        OpticalTab              matlab.ui.container.Tab
        MultiROICheckBox        matlab.ui.control.CheckBox
        N9PCheckBox             matlab.ui.control.CheckBox
        GaussianSliderLabel     matlab.ui.control.Label
        GaussianSlider          matlab.ui.control.Slider
        UniformityTab           matlab.ui.container.Tab
        GaussianSlider_2Label   matlab.ui.control.Label
        GaussianSlider_2        matlab.ui.control.Slider
        RightPanel              matlab.ui.container.Panel
        SummaryTextAreaLabel    matlab.ui.control.Label
        SummaryTextArea         matlab.ui.control.TextArea
        FigAxis                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % File dir/names
        fileDir
        file2D
        file3DRB
        file3DRD
        file3DLB
        file3DLD
        
        % Auditorium name
        Auditorium
        
        % Load 9 points data
        NP9
        
        % Individual ROI selection for Right and Left Eyes SCR
        ssL
        
        % Gaussian smooth value
        smo
        sigma
        
        % Output summary data
        MSG
    end
    
    properties (Access = private)
        NPselectionPopUp % 9points selection
        popUpHandle
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%<<< version 1.0, First release 2019/Sep. by John Wu >>>%%
        
            app.MultiROICheckBox.Value=0; 
            app.ssL=0;
            app.N9PCheckBox.Value=0; 
            app.NP9=0;
            app.sigma=app.GaussianSlider_2.Value;
        end

        % Button pushed function: Select_2DB
        function Select_2DBPushed(app, event)
            app.file2D=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the 2D Bright Image.');
            app.Edit_2DB.Value=app.file2D(1:end-4);
        end

        % Button pushed function: Select_Folder
        function Select_FolderButtonPushed(app, event)
            app.fileDir=uigetdir('Choose Director to start with.');
            app.Edit_Folder.Value=app.fileDir;        
            app.fileDir=[app.fileDir '/']; 
        end

        % Value changed function: Edit_Folder
        function Edit_FolderValueChanged(app, event)
            %value = app.Edit_Folder.Value;
            
        end

        % Button pushed function: Select_3DRB
        function Select_3DRBButtonPushed(app, event)
            app.file3DRB=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the 3D-Right Eye Bright Image.');
            app.Edit_3DRB.Value=app.file3DRB(1:end-4);
        end

        % Button pushed function: Select_3DRD
        function Select_3DRDButtonPushed(app, event)
            app.file3DRD=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the 3D-Right Eye Dark Image.');
            app.Edit_3DRD.Value=app.file3DRD(1:end-4);
        end

        % Button pushed function: Select_3DLB
        function Select_3DLBButtonPushed(app, event)
            app.file3DLB=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the 3D-Left Eye Bright Image.');
            app.Edit_3DLB.Value=app.file3DLB(1:end-4); 
        end

        % Button pushed function: Select_3DLD
        function Select_3DLDButtonPushed(app, event)
            app.file3DLD=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the 3D-Left Eye Dark Image.');
            fprintf(app.Edit_3DLD.Value)
            app.Edit_3DLD.Value=app.file3DLD(1:end-4);
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.Auditorium = app.NameEditField.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            selectedTab = app.TabGroup.SelectedTab.Title;
            switch selectedTab
                case 'Optical'
%                     if app.MultiROICheckBox.Value==0; app.ssL=0; end
%                     if app.N9PCheckBox.Value==0; app.NP9=0; end
                    Screen_Analysis(app);
                    app.SummaryTextArea.Value= sprintf([app.MSG{1:end}]);
                    
                case 'Uniformity'
%                     app.sigma=app.GaussianSlider_2.Value;
                    Screen_Uniformity(app);
                    app.SummaryTextArea.Value= sprintf([app.MSG{1:end}]);
            end
            
        end

        % Value changed function: SummaryTextArea
        function SummaryTextAreaValueChanged(app, event)
%             value = app.SummaryTextArea.Value;
            
        end

        % Value changed function: N9PCheckBox
        function N9PCheckBoxValueChanged(app, event)
            app.NP9 = app.N9PCheckBox.Value;
            if app.NP9==1
                popUpHandle = NPselectionPopUp(app);
            end
        end

        % Callback function
        function GaussianSliderValueChanged(app, event)
            app.smo = app.GaussianSlider.Value;
            
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.fileDir=''; app.Edit_Folder.Value='';
            app.file2D='';  app.Edit_2DB.Value='';
            app.file3DRB=''; app.Edit_3DRB.Value='';
            app.file3DRD=''; app.Edit_3DRD.Value='';
            app.file3DLB=''; app.Edit_3DLB.Value='';
            app.file3DLD=''; app.Edit_3DLD.Value='';
            app.Auditorium=''; app.NameEditField.Value='';
            app.SummaryTextArea.Value='';
        end

        % Value changed function: GaussianSlider
        function GaussianSliderValueChanged2(app, event)
            app.smo = app.GaussianSlider.Value;
            
        end

        % Value changed function: MultiROICheckBox
        function MultiROICheckBoxValueChanged(app, event)

            app.ssL=app.MultiROICheckBox.Value*3;
        end

        % Callback function
        function AnalysisButtonPushed(app, event)
            Screen_Uniformity(app);
            app.SummaryTextArea.Value= sprintf([app.MSG{1:end}]);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab.Title;
        end

        % Value changed function: GaussianSlider_2
        function GaussianSlider_2ValueChanged(app, event)
            app.sigma = app.GaussianSlider_2.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ScreenAnalysis.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {408, 408};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScreenAnalysis = uifigure('Visible', 'off');
            app.ScreenAnalysis.Name = 'Screen Analyzer 1.1';
            app.ScreenAnalysis.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ScreenAnalysis);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataFileSelectionLabel = uilabel(app.LeftPanel);
            app.DataFileSelectionLabel.Text = 'Data File Selection';
            app.NameEditFieldLabel = uilabel(app.LeftPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.LeftPanel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ClearAllButton = uibutton(app.LeftPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.Select_2DB = uibutton(app.LeftPanel, 'push');
            app.Select_2DB.ButtonPushedFcn = createCallbackFcn(app, @Select_2DBPushed, true);
            app.Select_2DB.Text = '2D Bright';
            app.Select_Folder = uibutton(app.LeftPanel, 'push');
            app.Select_Folder.ButtonPushedFcn = createCallbackFcn(app, @Select_FolderButtonPushed, true);
            app.Select_Folder.Text = 'Folder';
            app.Select_3DRB = uibutton(app.LeftPanel, 'push');
            app.Select_3DRB.ButtonPushedFcn = createCallbackFcn(app, @Select_3DRBButtonPushed, true);
            app.Select_3DRB.Text = '3D LKRW-Bright';
            app.Select_3DRD = uibutton(app.LeftPanel, 'push');
            app.Select_3DRD.ButtonPushedFcn = createCallbackFcn(app, @Select_3DRDButtonPushed, true);
            app.Select_3DRD.Text = '3D LKRW-Dark';
            app.Select_3DLB = uibutton(app.LeftPanel, 'push');
            app.Select_3DLB.ButtonPushedFcn = createCallbackFcn(app, @Select_3DLBButtonPushed, true);
            app.Select_3DLB.Text = '3D LWRK-Bright';
            app.Select_3DLD = uibutton(app.LeftPanel, 'push');
            app.Select_3DLD.ButtonPushedFcn = createCallbackFcn(app, @Select_3DLDButtonPushed, true);
            app.Select_3DLD.Text = '3D LWRK-Dark';
            app.Edit_Folder = uieditfield(app.LeftPanel, 'text');
            app.Edit_Folder.ValueChangedFcn = createCallbackFcn(app, @Edit_FolderValueChanged, true);
            app.Edit_2DB = uieditfield(app.LeftPanel, 'text');
            app.Edit_3DRB = uieditfield(app.LeftPanel, 'text');
            app.Edit_3DRD = uieditfield(app.LeftPanel, 'text');
            app.Edit_3DLB = uieditfield(app.LeftPanel, 'text');
            app.Edit_3DLD = uieditfield(app.LeftPanel, 'text');
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'reald_logo_detail.png';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.OpticalTab = uitab(app.TabGroup);
            app.OpticalTab.Title = 'Optical';
            app.MultiROICheckBox = uicheckbox(app.OpticalTab);
            app.MultiROICheckBox.ValueChangedFcn = createCallbackFcn(app, @MultiROICheckBoxValueChanged, true);
            app.MultiROICheckBox.Text = 'Multi-ROI';
            app.N9PCheckBox = uicheckbox(app.OpticalTab);
            app.N9PCheckBox.ValueChangedFcn = createCallbackFcn(app, @N9PCheckBoxValueChanged, true);
            app.N9PCheckBox.Enable = 'off';
            app.N9PCheckBox.Text = '9 Images';
            app.GaussianSliderLabel = uilabel(app.OpticalTab);
            app.GaussianSliderLabel.Text = 'Gaussian';
            app.GaussianSlider = uislider(app.OpticalTab);
            app.GaussianSlider.Limits = [2 160];
            app.GaussianSlider.MajorTicks = [4 32 64 96 128 160];
            app.GaussianSlider.MajorTickLabels = {'4', '32', '64', '96', '128', '160'};
            app.GaussianSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianSliderValueChanged2, true);
            app.GaussianSlider.MinorTicks = [0 4 8 16];
            app.GaussianSlider.Value = 64;
            app.UniformityTab = uitab(app.TabGroup);
            app.UniformityTab.Title = 'Uniformity';
            app.GaussianSlider_2Label = uilabel(app.UniformityTab);
            app.GaussianSlider_2Label.Text = 'Gaussian';
            app.GaussianSlider_2 = uislider(app.UniformityTab);
            app.GaussianSlider_2.Limits = [30 120];
            app.GaussianSlider_2.MajorTicks = [30 60 120];
            app.GaussianSlider_2.ValueChangedFcn = createCallbackFcn(app, @GaussianSlider_2ValueChanged, true);
            app.GaussianSlider_2.MinorTicks = 90;
            app.GaussianSlider_2.Value = 60;
            app.RightPanel = uipanel(app.GridLayout);
            app.SummaryTextAreaLabel = uilabel(app.RightPanel);
            app.SummaryTextAreaLabel.Text = 'Summary';
            app.SummaryTextArea = uitextarea(app.RightPanel);
            app.SummaryTextArea.ValueChangedFcn = createCallbackFcn(app, @SummaryTextAreaValueChanged, true);
            app.FigAxis = uiaxes(app.RightPanel);
            title(app.FigAxis, 'ULTIMATE SCREEN ANALYZER')
            xlabel(app.FigAxis, '')
            ylabel(app.FigAxis, '')
            % Show the figure after all components are created
            app.ScreenAnalysis.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScreenAnalysis_Main_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScreenAnalysis)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScreenAnalysis)
        end
    end
end